classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        IRotationsTab                   matlab.ui.container.Tab
        PartAandPartBRotationAnimationsLabel  matlab.ui.control.Label
        iEulerZYZRotationLabel          matlab.ui.control.Label
        iiRPYRotationLabel              matlab.ui.control.Label
        AnimateEulerTransformationButton  matlab.ui.control.Button
        AnimateRPYTransformationButton  matlab.ui.control.Button
        RotationaboutZYZphithetapsidegEditFieldLabel  matlab.ui.control.Label
        RotationaboutZYZphithetapsidegEditField  matlab.ui.control.EditField
        RotationanglesdegEditFieldLabel  matlab.ui.control.Label
        RotationanglesdegEditField      matlab.ui.control.EditField
        FixedFrameRotationaboutDropDownLabel  matlab.ui.control.Label
        FixedFrameRotationaboutDropDown  matlab.ui.control.DropDown
        iiiQuaternionRotationLabel      matlab.ui.control.Label
        ivRotationaboutaVectorLabel     matlab.ui.control.Label
        AnimateQuaternionTransformationButton  matlab.ui.control.Button
        VectortorotateaboutLabel        matlab.ui.control.Label
        VectortorotateaboutEditField    matlab.ui.control.EditField
        RotationAngledegLabel           matlab.ui.control.Label
        RotationAngledegEditField       matlab.ui.control.EditField
        Quaternionsv1v2v3sscalarcomponentv1v2v3vectorcomponentsLabel  matlab.ui.control.Label
        Quaternionsv1v2v3sscalarportionv1v2v3vectorcomponentsEditField  matlab.ui.control.EditField
        TranslationaboutXYZdefualt000EditFieldLabel  matlab.ui.control.Label
        TranslationaboutXYZdefualt000EditField  matlab.ui.control.EditField
        TranslationLabel                matlab.ui.control.Label
        RotationLabel                   matlab.ui.control.Label
        AnimateVectorTransformationButton  matlab.ui.control.Button
        IIRobotCreationandKinematicsTab  matlab.ui.container.Tab
        RobotQuestionaireButton         matlab.ui.control.Button
        CreateARobotLabel               matlab.ui.control.Label
        UITable                         matlab.ui.control.Table
        UseoneofthefollowingtodescribetheRobotLabel  matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        DHTableLabel                    matlab.ui.control.Label
        TotalnumberofjointsEditFieldLabel  matlab.ui.control.Label
        TotalnumberofjointsEditField    matlab.ui.control.EditField
        CreateRobotfromDHButton         matlab.ui.control.Button
        GetForwardKinematicsButton      matlab.ui.control.Button
        GetWorskpaceButton              matlab.ui.control.Button
        GetInverseKinematicsButton      matlab.ui.control.Button
        GetSingularitesButton           matlab.ui.control.Button
        WorkspaceDensitySliderLabel     matlab.ui.control.Label
        WorkspaceDensitySlider          matlab.ui.control.Slider
        maxjointlimitsradEditFieldLabel  matlab.ui.control.Label
        maxjointlimitsradEditField      matlab.ui.control.EditField
        minjointlimitsradEditFieldLabel  matlab.ui.control.Label
        minjointlimitsradEditField      matlab.ui.control.EditField
        PartCForwardKinematicsLabel     matlab.ui.control.Label
        ForwardKinematicsforaparticularq1xNdegEditFieldLabel  matlab.ui.control.Label
        ForwardKinematicsforaparticularq1xNdegEditField  matlab.ui.control.EditField
        iiLabel                         matlab.ui.control.Label
        iLabel                          matlab.ui.control.Label
        ForwardKinematicsfromDHparametersButton  matlab.ui.control.Button
        PartDWorkspaceandSingularitiesLabel  matlab.ui.control.Label
        PartEInverseKinematicsLabel     matlab.ui.control.Label
        q01xNdegEditFieldLabel          matlab.ui.control.Label
        q01xNdegEditField               matlab.ui.control.EditField
        TakeEndEffectorPoseT0HfromRotationsTabButton  matlab.ui.control.Button
        iInverseKinematicsatagivenq0Label  matlab.ui.control.Label
        iiInverseKinematicsusingOptimizationLabel  matlab.ui.control.Label
        q01xNdegEditField_2Label        matlab.ui.control.Label
        q01xNdegEditField_2             matlab.ui.control.EditField
        TakeEndEffectorPoseT0HfromRotationsTabButton_2  matlab.ui.control.Button
        GetInverseKinematicsikuncButton  matlab.ui.control.Button
        GetInverseKinematicsikconTakesjointlimitsifdefinedButton  matlab.ui.control.Button
        IIIDifferentialKinematicsandDynamicsTab  matlab.ui.container.Tab
        GetJacobianEquationsButton      matlab.ui.control.Button
        GetNumericJacobianButton        matlab.ui.control.Button
        GetVelocityofEndEffectorXe_dotButton  matlab.ui.control.Button
        GetLagrangianEquationsButton    matlab.ui.control.Button
        UITable2                        matlab.ui.control.Table
        PlotendeffectorposeandvelocityButton_2  matlab.ui.control.Button
        PartFDifferentialKinematicsLabel  matlab.ui.control.Label
        PartGInverseDifferentialKinematicsLabel  matlab.ui.control.Label
        PartHDynamicsLabel              matlab.ui.control.Label
        GetJointVelocitiesq_dotButton   matlab.ui.control.Button
        ForconstantmotortorqueTcEditFieldLabel  matlab.ui.control.Label
        ForconstantmotortorqueTcEditField  matlab.ui.control.EditField
        Robotlinkposeq1xNradLabel       matlab.ui.control.Label
        Robotlinkposeq1xNradEditField   matlab.ui.control.EditField
        Robotlinkvelocityq_dot1xNradEditFieldLabel  matlab.ui.control.Label
        Robotlinkvelocityq_dot1xNradEditField  matlab.ui.control.EditField
        Gravityvectorg01xNdegLabel      matlab.ui.control.Label
        Gravityvectorg01xNdegEditField  matlab.ui.control.EditField
        InitalEndEffectorPoseq01xNradEditFieldLabel  matlab.ui.control.Label
        InitalEndEffectorPoseq01xNradEditField  matlab.ui.control.EditField
        InitalEndEffectorVelocityqd01xNradsEditFieldLabel  matlab.ui.control.Label
        InitalEndEffectorVelocityqd01xNradsEditField  matlab.ui.control.EditField
        Robotlinkposeq1xNradEditField_2Label  matlab.ui.control.Label
        Robotlinkposeq1xNradEditField_2  matlab.ui.control.EditField
        EndEffectorvelocityXe_dot1x6radrpyfixedframeEditFieldLabel  matlab.ui.control.Label
        EndEffectorvelocityXe_dot1x6radrpyfixedframeEditField  matlab.ui.control.EditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %clear all % Of no use. Since it just clears the local workspace/scope. Not the global workspace
            close all
            translation = [0, 0, 0];
            T0H = eye(4);
            % make an empty .mat file to which data can be written/appended and read
            num_samples = 99; % number of samples. Used later in the program for workspace and singularities.
            %workspace_density = app.WorkspaceDensitySlider.Value;
            workspace_density = 20*0.01; % defualt value. Same appears on slider initially
            save('ui_data.mat') % no append flag provided since a fresh copy of ui_data.mat is to be created
            % load corke toolbox
            run('../robot-9.10/rvctools/startup_rvc.m')
        end

        % Value changed function: 
        % TranslationaboutXYZdefualt000EditField
        function TranslationaboutXYZdefualt000EditFieldValueChanged(app, event)
            translation = str2num(app.TranslationaboutXYZdefualt000EditField.Value);
            display('Translate: ')
            display(translation)
            save("ui_data.mat", "translation", "-append");
        end

        % Value changed function: 
        % RotationaboutZYZphithetapsidegEditField
        function RotationaboutZYZphithetapsidegEditFieldValueChanged(app, event)
            value = app.RotationaboutZYZphithetapsidegEditField.Value;
            display('Euler ZYZ:')
            display(str2num(value))
        end

        % Button pushed function: AnimateEulerTransformationButton
        function AnimateEulerTransformationButtonPushed(app, event)
            close all;
            load("ui_data.mat", "translation");
            rotation = str2num(app.RotationaboutZYZphithetapsidegEditField.Value);
            T0H = rak_animate_eul(translation, rotation);
            display(T0H)
%             app.Output_T0HTextArea.Value = evalc("T0H");
            save("ui_data.mat", "T0H", "-append");
        end

        % Value changed function: RotationanglesdegEditField
        function RotationanglesdegEditFieldValueChanged(app, event)
            value = app.RotationanglesdegEditField.Value;
            display('RPY angles: ')
            display(str2num(value))
        end

        % Value changed function: FixedFrameRotationaboutDropDown
        function FixedFrameRotationaboutDropDownValueChanged(app, event)
            value = app.FixedFrameRotationaboutDropDown.Value;
            display('RPY order: ')
            display(value)
        end

        % Button pushed function: AnimateRPYTransformationButton
        function AnimateRPYTransformationButtonPushed(app, event)
            close all;
            load("ui_data.mat", "translation");            
            angles = str2num(app.RotationanglesdegEditField.Value);
            order = app.FixedFrameRotationaboutDropDown.Value;
            T0H = rak_animate_rpy(translation, angles, order);
            display(T0H)
%             app.Output_T0HTextArea.Value = evalc("T0H");
            save("ui_data.mat", "T0H", "-append")
        end

        % Button pushed function: RobotQuestionaireButton
        function RobotQuestionaireButtonPushed(app, event)
            num_links = str2num(app.TotalnumberofjointsEditField.Value);
            [s, num_links, joint_type, ax_rot, link_ax, link_len] = rak_mit_dhfactor_string(num_links)
            % For quick debugging
            %load('./dh_param1_new.mat');
            [R, joint_type] = rak_dhfactor_dhparam(s, num_links, joint_type);
            [a, alph, d, thet] = rak_dhfactor_nonsyms(R, joint_type, link_len);
            uit_data = [joint_type, a, alph, d, thet];
            Robo = rak_dhfactor_robolink(a, alph, d, thet, joint_type, link_len);
            sl_no = [1:num_links]';
            tmp_str = [];
            for i = 1:num_links
                tmp_str = [tmp_str, convertCharsToStrings(joint_type(i))];
            end
            joint_type = tmp_str;
            table1_data = [sl_no,  joint_type', link_len', a', alph', d', thet'];
            set(app.UITable, 'Data', table1_data);
            
            sl_no = str2double(table1_data(:,1)); % not used
            joint_type = (table1_data(:,2));
            link_len = str2double(table1_data(:,3));
            a = str2double(table1_data(:,4));
            alph = str2double(table1_data(:,5));
            d = str2double(table1_data(:,6));
            thet = str2double(table1_data(:,7));
            dh_numeric = [a, alph, d, thet];
            dh_sym_theta = modify_dh(dh_numeric, joint_type);   
            
            save('ui_data.mat', '-append');
            
        end

        % Value changed function: TotalnumberofjointsEditField
        function TotalnumberofjointsEditFieldValueChanged(app, event)
            num_links = str2num(app.TotalnumberofjointsEditField.Value);
            % Init Table 1
            %serial number col, a, alpha, d, theta
            joint_type_init = [];
            for i = 1:num_links
                joint_type_init = [joint_type_init, "p/r"];        
            end
            sl_no = [1:num_links]';
            table1_init = [ sl_no,  joint_type_init', zeros(num_links, 5) ];
            set(app.UITable, 'Data', table1_init);
            table1_data = table1_init;
            
            % Init Table 2
            % serial no, gear ratio, link mass, cog, link inertia, motor inertia
            cog_init = [];
            for i = 1:num_links
                cog_init = [cog_init, "0 0 0"];
            end
            link_inertia_init = [];
            for i = 1:num_links
                link_inertia_init = [link_inertia_init, "0 0 0"];        
            end
            table2_init = [ sl_no,  zeros(num_links, 2), cog_init', link_inertia_init', zeros(num_links, 1)];
            set(app.UITable2, 'Data', table2_init);
            table2_data = table2_init;
            save('ui_data.mat', '-append');
        end

        % Button pushed function: CreateRobotfromDHButton
        function CreateRobotfromDHButtonPushed(app, event)
            load("ui_data.mat");
%             table1_data = app.UITable.Data;
            sl_no = str2double(table1_data(:,1)); % not used
            joint_type = (table1_data(:,2));
            link_len = str2double(table1_data(:,3));
            a = str2double(table1_data(:,4));
            alph = str2double(table1_data(:,5));
            d = str2double(table1_data(:,6));
            thet = str2double(table1_data(:,7));
            dh_numeric = [a, alph, d, thet];
            dh_sym_theta = modify_dh(dh_numeric, joint_type);
            Robo = rak_dhfactor_robolink(a, alph, d, thet, joint_type, link_len);
            save('ui_data.mat', '-append');
        end

        % Callback function
        function ForwardKinematicsq01xNdegEditFieldValueChanged(app, event)
            q0 = str2num(app.ForwardKinematicsq01xNdegEditField.Value);
            save('ui_data.mat', 'q0', '-append');
        end

        % Button pushed function: GetForwardKinematicsButton
        function GetForwardKinematicsButtonPushed(app, event)
            load("ui_data.mat");
            T_fkine = Robo.fkine(q_fwd_kin, 'deg');
            display(T_fkine)
%             app.Fwd_KinTextArea.Value = " ";
%             app.Fwd_KinTextArea.Value = evalc("T_fkine");
%             app.Fwd_KinTextArea.Value = evalc("T_fkine2_sym");
            Robo.plot(q_fwd_kin);
            save("ui_data.mat", "T_fkine", "q_fwd_kin", "-append");
            rak_animate_transmatrix(T_fkine);
        end

        % Value changed function: VectortorotateaboutEditField
        function VectortorotateaboutEditFieldValueChanged(app, event)
            vec = str2num(app.VectortorotateaboutEditField.Value);
            save("ui_data.mat", "vec", "-append");
        end

        % Value changed function: RotationAngledegEditField
        function RotationAngledegEditFieldValueChanged(app, event)
            vec_angle = str2num(app.RotationAngledegEditField.Value);
            save("ui_data.mat", "vec_angle", "-append");
        end

        % Button pushed function: AnimateVectorTransformationButton
        function AnimateVectorTransformationButtonPushed(app, event)
            load("ui_data.mat");
            T0H = rak_animate_vec(vec, vec_angle, translation);
            T0H(1:3, 4) = translation';
            display(T0H)
%             app.Output_T0HTextArea.Value = evalc("T0H");
            save("ui_data.mat", "T0H", "-append")
        end

        % Value changed function: 
        % Quaternionsv1v2v3sscalarportionv1v2v3vectorcomponentsEditField
        function Quaternionsv1v2v3sscalarportionv1v2v3vectorcomponentsEditFieldValueChanged(app, event)
            quat = Quaternion(str2num(app.Quaternionsv1v2v3sscalarportionv1v2v3vectorcomponentsEditField.Value));
            save("ui_data.mat", "quat", "-append");
        end

        % Button pushed function: 
        % AnimateQuaternionTransformationButton
        function AnimateQuaternionTransformationButtonPushed(app, event)
            load("ui_data.mat");
            T0H = rak_animate_quat(quat, translation);
            T0H(1:3, 4) = translation';
            display(T0H)
%             app.Output_T0HTextArea.Value = evalc("T0H");
            save("ui_data.mat", "T0H", "-append");
        end

        % Button pushed function: GetWorskpaceButton
        function GetWorskpaceButtonPushed(app, event)
            load("ui_data.mat");
            joint_lts = [joint_lts_min', joint_lts_max'];
            [X,Y,Z] = get_workspace(dh_sym_theta, joint_type, joint_lts, num_samples);
            plot_workspace(X, Y, Z, workspace_density); 
            save("ui_data.mat", "X", "Y", "Z", "-append")
        end

        % Button pushed function: GetInverseKinematicsButton
        function GetInverseKinematicsButtonPushed(app, event)
            load("ui_data.mat");
%             listOfVariables = who();
%             if(~ismember('q0', listOfVariables))
%                 q0 = zeros(1,length(num_links));
%             end
            q_ikine = ikine2(Robo, T_for_ikine, q0_ikine, dh_sym_theta, joint_type);
            save("ui_data.mat", "q_ikine", "-append")
        end

        % Button pushed function: GetJacobianEquationsButton
        function GetJacobianEquationsButtonPushed(app, event)
            load("ui_data.mat");
            J_sym = jacob_sym(dh_sym_theta, joint_type);
            display(J_sym)
            save("ui_data.mat", "J_sym", "-append")
        end

        % Button pushed function: GetNumericJacobianButton
        function GetNumericJacobianButtonPushed(app, event)
            load("ui_data.mat");
            J_num_0 = Robo.jacob0(q_jacob);
            display(J_num_0)
            save("ui_data.mat", "J_num_0", "-append")
        end

        % Button pushed function: 
        % GetVelocityofEndEffectorXe_dotButton
        function GetVelocityofEndEffectorXe_dotButtonPushed(app, event)
            load("ui_data.mat");
            xe_dot = J_num_0*q_dot';
            display(xe_dot)
            save("ui_data.mat", "xe_dot", "-append");        
        end

        % Button pushed function: GetSingularitesButton
        function GetSingularitesButtonPushed(app, event)
            load("ui_data.mat");
            joint_lts = [joint_lts_min', joint_lts_max'];
            qsing = singularities(dh_sym_theta, joint_type, joint_lts, num_samples);
            save("ui_data.mat", "qsing", "-append");
        end

        % Button pushed function: GetLagrangianEquationsButton
        function GetLagrangianEquationsButtonPushed(app, event)
            load("ui_data.mat");
            [B,C,G] = lagrangian(dh_sym_theta,joint_type,g0);
            save("ui_data.mat", "B", "C", "G", "-append");
        end

        % Value changed function: WorkspaceDensitySlider
        function WorkspaceDensitySliderValueChanged(app, event)
            workspace_density = app.WorkspaceDensitySlider.Value;
            save("ui_data.mat", "workspace_density", "-append");
        end

        % Value changed function: maxjointlimitsradEditField
        function maxjointlimitsradEditFieldValueChanged(app, event)
            joint_lts_max = str2num(app.maxjointlimitsradEditField.Value);
            save("ui_data.mat", "joint_lts_max", "-append");
        end

        % Value changed function: minjointlimitsradEditField
        function minjointlimitsradEditFieldValueChanged(app, event)
            joint_lts_min = str2num(app.minjointlimitsradEditField.Value);
            save("ui_data.mat", "joint_lts_min", "-append");
        end

        % Value changed function: Gravityvectorg01xNdegEditField
        function gravityvectorEditFieldValueChanged(app, event)
            syms g
            grav_vector = transpose(str2num(app.Gravityvectorg01xNdegEditField.Value));
            g0 = grav_vector*g;
            save("ui_data.mat", "grav_vector", "g0", "-append");
        end

        % Callback function
        function fdyn_deprecatedButtonPushed(app, event)
            load("ui_data.mat");
            table2_data = app.UITable2.Data;
%             gear_ratio = table2_data(:,2);
%             link_mass = table2_data(:,3);
%             link_cog = str2num(table2_data(:,4));
%             link_inirtia = str2num(table2_data(:,5));
%             motor_inertia = table2_data(:,6);
%             for i = 1:num_links
%                 Robo.links(i).G = gear_ratio(i);
%                 Robo.links(i).m = link_mass(i);
%                 Robo.links(i).r = link_cog(i);
%                 Robo.links(i).I = link_inirtia(i);
%                 Robo.links(i).Jm = motor_inertia(i);
%             end
%             fdyn2(R, q0, qd0, motor_torque);
%             save("ui_data.mat", "-append");
        end

        % Callback function
        function ForwardDynamicsqd01xNdegEditFieldValueChanged(app, event)
            qd0 = str2num(app.ForwardDynamicsqd01xNdegEditField.Value);
            save("ui_data.mat", "qd0", "-append");
        end

        % Value changed function: ForconstantmotortorqueTcEditField
        function ForconstantmotortorqueTcEditFieldValueChanged(app, event)
            motor_torque = str2num(app.ForconstantmotortorqueTcEditField.Value);
            save("ui_data.mat", "motor_torque", "-append");
        end

        % Callback function
        function two_link_planarButtonPushed(app, event)
            num_links = 2;
            app.TotalnumberofjointsEditField.Value = num2str(num_links);
            % Tables 1 Init
            joint_type_init = [];
            for i = 1:num_links
                joint_type_init = [joint_type_init, "r"];        
            end
            sl_no = [1:num_links]';
            a = [4,2];
            al = zeros(1,num_links);
            d = zeros(1,num_links);
            th = zeros(1,num_links);
            table1_init = [ sl_no,  joint_type_init', a', al', d', th'];
            set(app.UITable, 'Data', table1_init);
            % q max/min Init
            qmax = [pi/2, pi/2];
            qmin = [-pi/2, -pi/2];
            app.maxjointlimitsradEditField.Value = num2str(qmax);
            app.minjointlimitsradEditField.Value = num2str(qmin);
        end

        % Callback function
        function Jointlocationsq1xNdegEditFieldValueChanged(app, event)
            q = str2num(app.Jointlocationsq1xNdegEditField.Value);
            save("ui_data.mat", "q", "-append");
        end

        % Button pushed function: 
        % PlotendeffectorposeandvelocityButton_2
        function PlotendeffectorposeandvelocityButton_2Pushed(app, event)
            load("ui_data.mat");
%             table2_data = app.UITable2.Data;
            sl_no = str2double(table2_data(:,1)); % not used
            gear_ratio = str2double(table2_data(:,2));
            link_mass = str2double(table2_data(:,3));
            link_cog = zeros(num_links, 3);
            link_inertia = zeros(num_links, 3);
            for i = 1:size(table2_data(:,4),1)
                tmp_str = table2_data(i,4);
                link_cog(i,:) = eval(strcat("[", tmp_str,"]"));
                tmp_str = table2_data(i,5);
                link_inertia(i,:) = eval(strcat("[", tmp_str,"]"));
            end            
            motor_inertia = str2double(table2_data(:,6));
            for i = 1:num_links
                Robo.links(i).G = gear_ratio(i);
                Robo.links(i).m = link_mass(i);
                Robo.links(i).r = link_cog(i,:);
                Robo.links(i).I = link_inertia(i,:);
                Robo.links(i).Jm = motor_inertia(i);
            end
            fdyn2(Robo, q0_dyn, qd0_dyn, motor_torque);
            save('ui_data.mat', '-append');
        end

        % Cell edit callback: UITable2
        function UITable2CellEdit(app, event)
            % Don't just load the entire ui_data.mat file. It will wipe out
            % other variables that are present in this scope like event.
            load("ui_data.mat", "table2_data");
            indices = event.Indices;
            newData = event.NewData;
            table2_data(indices(1), indices(2)) = newData;
            save("ui_data.mat", "table2_data", "-append");
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            % Don't just load the entire ui_data.mat file. It will wipe out
            % other variables that are present in this scope like event.
            load("ui_data.mat", "table1_data");
            indices = event.Indices;
            newData = event.NewData;
            table1_data(indices(1), indices(2)) = newData;
            save("ui_data.mat", "table1_data", "-append");
        end

        % Value changed function: 
        % ForwardKinematicsforaparticularq1xNdegEditField
        function ForwardKinematicsforaparticularq1xNdegEditFieldValueChanged(app, event)
            q_fwd_kin = str2num(app.ForwardKinematicsforaparticularq1xNdegEditField.Value);
            save('ui_data.mat', 'q_fwd_kin', '-append');
        end

        % Button pushed function: 
        % ForwardKinematicsfromDHparametersButton
        function ForwardKinematicsfromDHparametersButtonPushed(app, event)
            load("ui_data.mat", "dh_sym_theta");
            T_fkine2_sym = fkine2(dh_sym_theta);
            display(T_fkine2_sym)
%             app.Fwd_KinTextArea.Value = evalc("T_fkine2_sym");
            save("ui_data.mat", "T_fkine2_sym", "-append");
        end

        % Value changed function: q01xNdegEditField
        function q01xNdegEditFieldValueChanged(app, event)
            q0_ikine = str2num(app.q01xNdegEditField.Value);
            save("ui_data.mat", "q0_ikine", "-append");
        end

        % Button pushed function: 
        % TakeEndEffectorPoseT0HfromRotationsTabButton
        function TakeEndEffectorPoseT0HfromRotationsTabButtonPushed(app, event)
            load("ui_data.mat");
            %T_for_ikine = Robo.fkine(q0_ikine, 'deg');
            %display(T_for_ikine)
            T_for_ikine = T0H;
            save("ui_data.mat", "T_for_ikine", "-append");            
        end

        % Value changed function: Robotlinkposeq1xNradEditField
        function Robotlinkposeq1xNradEditFieldValueChanged(app, event)
            q_jacob = str2num(app.Robotlinkposeq1xNradEditField.Value);
            save("ui_data.mat", "q_jacob", "-append");
        end

        % Value changed function: 
        % Robotlinkvelocityq_dot1xNradEditField
        function Robotlinkvelocityq_dot1xNradEditFieldValueChanged(app, event)
            q_dot = str2num(app.Robotlinkvelocityq_dot1xNradEditField.Value);
            save("ui_data.mat", "q_dot", "-append");
        end

        % Value changed function: 
        % InitalEndEffectorPoseq01xNradEditField
        function InitalEndEffectorPoseq01xNradEditFieldValueChanged(app, event)
            q0_dyn = str2num(app.InitalEndEffectorPoseq01xNradEditField.Value);
            save("ui_data.mat", "q0_dyn", "-append");
        end

        % Value changed function: 
        % InitalEndEffectorVelocityqd01xNradsEditField
        function InitalEndEffectorVelocityqd01xNradsEditFieldValueChanged(app, event)
            qd0_dyn = str2num(app.InitalEndEffectorVelocityqd01xNradsEditField.Value);
            save("ui_data.mat", "qd0_dyn", "-append");
        end

        % Value changed function: Robotlinkposeq1xNradEditField_2
        function Robotlinkposeq1xNradEditField_2ValueChanged(app, event)
            q_inv_diff = str2num(app.Robotlinkposeq1xNradEditField_2.Value);
            save("ui_data.mat", "q_inv_diff", "-append");
        end

        % Value changed function: 
        % EndEffectorvelocityXe_dot1x6radrpyfixedframeEditField
        function EndEffectorvelocityXe_dot1x6radrpyfixedframeEditFieldValueChanged(app, event)
            xe_dot_inv_diff = str2num(app.EndEffectorvelocityXe_dot1x6radrpyfixedframeEditField.Value);
            save("ui_data.mat", "xe_dot_inv_diff", "-append");
        end

        % Button pushed function: GetJointVelocitiesq_dotButton
        function GetJointVelocitiesq_dotButtonPushed(app, event)
            load("ui_data.mat");
            J_num_0_inv_diff = Robo.jacob0(q_inv_diff);
            q_dot_inv_diff = pinv(J_num_0_inv_diff)*xe_dot_inv_diff';
            display(q_dot_inv_diff)
            save("ui_data.mat", "J_num_0_inv_diff", "q_dot_inv_diff", "-append");
        end

        % Callback function
        function text_outputButtonPushed(app, event)
%             T = [2 3 4 45 5  5; 5  67 7 3 5 5]';
        end

        % Value changed function: q01xNdegEditField_2
        function q01xNdegEditField_2ValueChanged(app, event)
            q0_ikine_ii = str2num(app.q01xNdegEditField_2.Value);
            save("ui_data.mat", "q0_ikine_ii", "-append");            
        end

        % Button pushed function: 
        % TakeEndEffectorPoseT0HfromRotationsTabButton_2
        function TakeEndEffectorPoseT0HfromRotationsTabButton_2Pushed(app, event)
            load("ui_data.mat");
            %T_for_ikine = Robo.fkine(q0_ikine, 'deg');
            %display(T_for_ikine)
            T_for_ikine_ii = T0H;
            save("ui_data.mat", "T_for_ikine_ii", "-append");                 
        end

        % Button pushed function: GetInverseKinematicsikuncButton
        function GetInverseKinematicsikuncButtonPushed(app, event)
            load("ui_data.mat")
            q_ikine_ii = Robo.ikunc(T_for_ikine_ii);
            display(q_ikine_ii)
            save("ui_data.mat", "q_ikine_ii", "-append");
        end

        % Button pushed function: 
        % GetInverseKinematicsikconTakesjointlimitsifdefinedButton
        function GetInverseKinematicsikconTakesjointlimitsifdefinedButtonPushed(app, event)
            load("ui_data.mat")
            q_ikine_ii2 = Robo.ikcon(T_for_ikine_ii);
            display(q_ikine_ii2)
            save("ui_data.mat", "q_ikine_ii2", "-append");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.IRotationsTab = uitab(app.TabGroup);
            app.IRotationsTab.Title = 'I. Rotations';
            app.PartAandPartBRotationAnimationsLabel = uilabel(app.IRotationsTab);
            app.PartAandPartBRotationAnimationsLabel.Text = 'Part A and Part B:  Rotation Animations';
            app.iEulerZYZRotationLabel = uilabel(app.IRotationsTab);
            app.iEulerZYZRotationLabel.Text = 'i. Euler (ZYZ) Rotation';
            app.iiRPYRotationLabel = uilabel(app.IRotationsTab);
            app.iiRPYRotationLabel.Text = 'ii. RPY Rotation';
            app.AnimateEulerTransformationButton = uibutton(app.IRotationsTab, 'push');
            app.AnimateEulerTransformationButton.ButtonPushedFcn = createCallbackFcn(app, @AnimateEulerTransformationButtonPushed, true);
            app.AnimateEulerTransformationButton.Text = 'Animate Euler Transformation';
            app.AnimateRPYTransformationButton = uibutton(app.IRotationsTab, 'push');
            app.AnimateRPYTransformationButton.ButtonPushedFcn = createCallbackFcn(app, @AnimateRPYTransformationButtonPushed, true);
            app.AnimateRPYTransformationButton.Text = 'Animate RPY Transformation';
            app.RotationaboutZYZphithetapsidegEditFieldLabel = uilabel(app.IRotationsTab);
            app.RotationaboutZYZphithetapsidegEditFieldLabel.Text = {'Rotation about Z, Y'', Z'''' :'; '(phi, theta, psi) (deg)'};
            app.RotationaboutZYZphithetapsidegEditField = uieditfield(app.IRotationsTab, 'text');
            app.RotationaboutZYZphithetapsidegEditField.ValueChangedFcn = createCallbackFcn(app, @RotationaboutZYZphithetapsidegEditFieldValueChanged, true);
            app.RotationanglesdegEditFieldLabel = uilabel(app.IRotationsTab);
            app.RotationanglesdegEditFieldLabel.Text = 'Rotation angles (deg):';
            app.RotationanglesdegEditField = uieditfield(app.IRotationsTab, 'text');
            app.RotationanglesdegEditField.ValueChangedFcn = createCallbackFcn(app, @RotationanglesdegEditFieldValueChanged, true);
            app.FixedFrameRotationaboutDropDownLabel = uilabel(app.IRotationsTab);
            app.FixedFrameRotationaboutDropDownLabel.Text = 'Fixed Frame Rotation about:';
            app.FixedFrameRotationaboutDropDown = uidropdown(app.IRotationsTab);
            app.FixedFrameRotationaboutDropDown.Items = {'ZYX', 'XYZ'};
            app.FixedFrameRotationaboutDropDown.ValueChangedFcn = createCallbackFcn(app, @FixedFrameRotationaboutDropDownValueChanged, true);
            app.FixedFrameRotationaboutDropDown.Value = 'ZYX';
            app.iiiQuaternionRotationLabel = uilabel(app.IRotationsTab);
            app.iiiQuaternionRotationLabel.Text = 'iii. Quaternion Rotation';
            app.ivRotationaboutaVectorLabel = uilabel(app.IRotationsTab);
            app.ivRotationaboutaVectorLabel.Text = 'iv. Rotation about a Vector';
            app.AnimateQuaternionTransformationButton = uibutton(app.IRotationsTab, 'push');
            app.AnimateQuaternionTransformationButton.ButtonPushedFcn = createCallbackFcn(app, @AnimateQuaternionTransformationButtonPushed, true);
            app.AnimateQuaternionTransformationButton.Text = 'Animate Quaternion Transformation';
            app.VectortorotateaboutLabel = uilabel(app.IRotationsTab);
            app.VectortorotateaboutLabel.Text = 'Vector to rotate about:';
            app.VectortorotateaboutEditField = uieditfield(app.IRotationsTab, 'text');
            app.VectortorotateaboutEditField.ValueChangedFcn = createCallbackFcn(app, @VectortorotateaboutEditFieldValueChanged, true);
            app.RotationAngledegLabel = uilabel(app.IRotationsTab);
            app.RotationAngledegLabel.Text = 'Rotation Angle (deg):';
            app.RotationAngledegEditField = uieditfield(app.IRotationsTab, 'text');
            app.RotationAngledegEditField.ValueChangedFcn = createCallbackFcn(app, @RotationAngledegEditFieldValueChanged, true);
            app.Quaternionsv1v2v3sscalarcomponentv1v2v3vectorcomponentsLabel = uilabel(app.IRotationsTab);
            app.Quaternionsv1v2v3sscalarcomponentv1v2v3vectorcomponentsLabel.Text = {'Quaternion [s, v1, v2, v3] :'; 's: scalar component '; 'v1, v2, v3: vector components'};
            app.Quaternionsv1v2v3sscalarportionv1v2v3vectorcomponentsEditField = uieditfield(app.IRotationsTab, 'text');
            app.Quaternionsv1v2v3sscalarportionv1v2v3vectorcomponentsEditField.ValueChangedFcn = createCallbackFcn(app, @Quaternionsv1v2v3sscalarportionv1v2v3vectorcomponentsEditFieldValueChanged, true);
            app.TranslationaboutXYZdefualt000EditFieldLabel = uilabel(app.IRotationsTab);
            app.TranslationaboutXYZdefualt000EditFieldLabel.Text = {'Translation about X, Y, Z :'; '(defualt: 0, 0, 0)'};
            app.TranslationaboutXYZdefualt000EditField = uieditfield(app.IRotationsTab, 'text');
            app.TranslationaboutXYZdefualt000EditField.ValueChangedFcn = createCallbackFcn(app, @TranslationaboutXYZdefualt000EditFieldValueChanged, true);
            app.TranslationLabel = uilabel(app.IRotationsTab);
            app.TranslationLabel.Text = '1. Translation';
            app.RotationLabel = uilabel(app.IRotationsTab);
            app.RotationLabel.Text = '2. Rotation';
            app.AnimateVectorTransformationButton = uibutton(app.IRotationsTab, 'push');
            app.AnimateVectorTransformationButton.ButtonPushedFcn = createCallbackFcn(app, @AnimateVectorTransformationButtonPushed, true);
            app.AnimateVectorTransformationButton.Text = 'Animate Vector Transformation';
            app.IIRobotCreationandKinematicsTab = uitab(app.TabGroup);
            app.IIRobotCreationandKinematicsTab.Title = 'II. Robot Creation and Kinematics';
            app.RobotQuestionaireButton = uibutton(app.IIRobotCreationandKinematicsTab, 'push');
            app.RobotQuestionaireButton.ButtonPushedFcn = createCallbackFcn(app, @RobotQuestionaireButtonPushed, true);
            app.RobotQuestionaireButton.Text = 'Robot Questionaire';
            app.CreateARobotLabel = uilabel(app.IIRobotCreationandKinematicsTab);
            app.CreateARobotLabel.Text = 'Create A Robot!';
            app.UITable = uitable(app.IIRobotCreationandKinematicsTab);
            app.UITable.ColumnName = {'Sl No'; 'joint_type'; 'link_length'; 'a'; 'alpha'; 'd'; 'theta'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [false true true true true true true];
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UseoneofthefollowingtodescribetheRobotLabel = uilabel(app.IIRobotCreationandKinematicsTab);
            app.UseoneofthefollowingtodescribetheRobotLabel.Text = 'Use one of the following to describe the Robot:';
            app.Label = uilabel(app.IIRobotCreationandKinematicsTab);
            app.Label.Text = '1.';
            app.Label_2 = uilabel(app.IIRobotCreationandKinematicsTab);
            app.Label_2.Text = '2.';
            app.DHTableLabel = uilabel(app.IIRobotCreationandKinematicsTab);
            app.DHTableLabel.Text = 'DH Table';
            app.TotalnumberofjointsEditFieldLabel = uilabel(app.IIRobotCreationandKinematicsTab);
            app.TotalnumberofjointsEditFieldLabel.Text = 'Total number of joints:';
            app.TotalnumberofjointsEditField = uieditfield(app.IIRobotCreationandKinematicsTab, 'text');
            app.TotalnumberofjointsEditField.ValueChangedFcn = createCallbackFcn(app, @TotalnumberofjointsEditFieldValueChanged, true);
            app.CreateRobotfromDHButton = uibutton(app.IIRobotCreationandKinematicsTab, 'push');
            app.CreateRobotfromDHButton.ButtonPushedFcn = createCallbackFcn(app, @CreateRobotfromDHButtonPushed, true);
            app.CreateRobotfromDHButton.Text = 'Create Robot from DH';
            app.GetForwardKinematicsButton = uibutton(app.IIRobotCreationandKinematicsTab, 'push');
            app.GetForwardKinematicsButton.ButtonPushedFcn = createCallbackFcn(app, @GetForwardKinematicsButtonPushed, true);
            app.GetForwardKinematicsButton.Text = {'Get Forward '; 'Kinematics'};
            app.GetWorskpaceButton = uibutton(app.IIRobotCreationandKinematicsTab, 'push');
            app.GetWorskpaceButton.ButtonPushedFcn = createCallbackFcn(app, @GetWorskpaceButtonPushed, true);
            app.GetWorskpaceButton.Text = 'Get Worskpace';
            app.GetInverseKinematicsButton = uibutton(app.IIRobotCreationandKinematicsTab, 'push');
            app.GetInverseKinematicsButton.ButtonPushedFcn = createCallbackFcn(app, @GetInverseKinematicsButtonPushed, true);
            app.GetInverseKinematicsButton.Text = 'Get Inverse Kinematics';
            app.GetSingularitesButton = uibutton(app.IIRobotCreationandKinematicsTab, 'push');
            app.GetSingularitesButton.ButtonPushedFcn = createCallbackFcn(app, @GetSingularitesButtonPushed, true);
            app.GetSingularitesButton.Text = 'Get Singularites';
            app.WorkspaceDensitySliderLabel = uilabel(app.IIRobotCreationandKinematicsTab);
            app.WorkspaceDensitySliderLabel.Text = {'Workspace'; 'Density:'};
            app.WorkspaceDensitySlider = uislider(app.IIRobotCreationandKinematicsTab);
            app.WorkspaceDensitySlider.ValueChangedFcn = createCallbackFcn(app, @WorkspaceDensitySliderValueChanged, true);
            app.WorkspaceDensitySlider.Value = 20;
            app.maxjointlimitsradEditFieldLabel = uilabel(app.IIRobotCreationandKinematicsTab);
            app.maxjointlimitsradEditFieldLabel.Text = 'max joint limits (rad)';
            app.maxjointlimitsradEditField = uieditfield(app.IIRobotCreationandKinematicsTab, 'text');
            app.maxjointlimitsradEditField.ValueChangedFcn = createCallbackFcn(app, @maxjointlimitsradEditFieldValueChanged, true);
            app.minjointlimitsradEditFieldLabel = uilabel(app.IIRobotCreationandKinematicsTab);
            app.minjointlimitsradEditFieldLabel.Text = 'min joint limits (rad)';
            app.minjointlimitsradEditField = uieditfield(app.IIRobotCreationandKinematicsTab, 'text');
            app.minjointlimitsradEditField.ValueChangedFcn = createCallbackFcn(app, @minjointlimitsradEditFieldValueChanged, true);
            app.PartCForwardKinematicsLabel = uilabel(app.IIRobotCreationandKinematicsTab);
            app.PartCForwardKinematicsLabel.Text = 'Part C: Forward Kinematics';
            app.ForwardKinematicsforaparticularq1xNdegEditFieldLabel = uilabel(app.IIRobotCreationandKinematicsTab);
            app.ForwardKinematicsforaparticularq1xNdegEditFieldLabel.Text = {'Forward Kinematics for a particular ''q'' '; '(1xN) (deg)'};
            app.ForwardKinematicsforaparticularq1xNdegEditField = uieditfield(app.IIRobotCreationandKinematicsTab, 'text');
            app.ForwardKinematicsforaparticularq1xNdegEditField.ValueChangedFcn = createCallbackFcn(app, @ForwardKinematicsforaparticularq1xNdegEditFieldValueChanged, true);
            app.iiLabel = uilabel(app.IIRobotCreationandKinematicsTab);
            app.iiLabel.Text = 'ii.';
            app.iLabel = uilabel(app.IIRobotCreationandKinematicsTab);
            app.iLabel.Text = 'i.';
            app.ForwardKinematicsfromDHparametersButton = uibutton(app.IIRobotCreationandKinematicsTab, 'push');
            app.ForwardKinematicsfromDHparametersButton.ButtonPushedFcn = createCallbackFcn(app, @ForwardKinematicsfromDHparametersButtonPushed, true);
            app.ForwardKinematicsfromDHparametersButton.Text = {'Forward Kinematics '; 'from DH parameters'};
            app.PartDWorkspaceandSingularitiesLabel = uilabel(app.IIRobotCreationandKinematicsTab);
            app.PartDWorkspaceandSingularitiesLabel.Text = 'Part D: Workspace and Singularities';
            app.PartEInverseKinematicsLabel = uilabel(app.IIRobotCreationandKinematicsTab);
            app.PartEInverseKinematicsLabel.Text = 'Part E: Inverse Kinematics';
            app.q01xNdegEditFieldLabel = uilabel(app.IIRobotCreationandKinematicsTab);
            app.q01xNdegEditFieldLabel.Text = 'q0: (1xN) (deg)';
            app.q01xNdegEditField = uieditfield(app.IIRobotCreationandKinematicsTab, 'text');
            app.q01xNdegEditField.ValueChangedFcn = createCallbackFcn(app, @q01xNdegEditFieldValueChanged, true);
            app.TakeEndEffectorPoseT0HfromRotationsTabButton = uibutton(app.IIRobotCreationandKinematicsTab, 'push');
            app.TakeEndEffectorPoseT0HfromRotationsTabButton.ButtonPushedFcn = createCallbackFcn(app, @TakeEndEffectorPoseT0HfromRotationsTabButtonPushed, true);
            app.TakeEndEffectorPoseT0HfromRotationsTabButton.Text = {'Take End Effector Pose (T0H) '; 'from Rotations Tab'};
            app.iInverseKinematicsatagivenq0Label = uilabel(app.IIRobotCreationandKinematicsTab);
            app.iInverseKinematicsatagivenq0Label.Text = 'i. Inverse Kinematics at a given q0';
            app.iiInverseKinematicsusingOptimizationLabel = uilabel(app.IIRobotCreationandKinematicsTab);
            app.iiInverseKinematicsusingOptimizationLabel.Text = 'ii. Inverse Kinematics using Optimization';
            app.q01xNdegEditField_2Label = uilabel(app.IIRobotCreationandKinematicsTab);
            app.q01xNdegEditField_2Label.Text = 'q0: (1xN) (deg)';
            app.q01xNdegEditField_2 = uieditfield(app.IIRobotCreationandKinematicsTab, 'text');
            app.q01xNdegEditField_2.ValueChangedFcn = createCallbackFcn(app, @q01xNdegEditField_2ValueChanged, true);
            app.TakeEndEffectorPoseT0HfromRotationsTabButton_2 = uibutton(app.IIRobotCreationandKinematicsTab, 'push');
            app.TakeEndEffectorPoseT0HfromRotationsTabButton_2.ButtonPushedFcn = createCallbackFcn(app, @TakeEndEffectorPoseT0HfromRotationsTabButton_2Pushed, true);
            app.TakeEndEffectorPoseT0HfromRotationsTabButton_2.Text = {'Take End Effector Pose (T0H) '; 'from Rotations Tab'};
            app.GetInverseKinematicsikuncButton = uibutton(app.IIRobotCreationandKinematicsTab, 'push');
            app.GetInverseKinematicsikuncButton.ButtonPushedFcn = createCallbackFcn(app, @GetInverseKinematicsikuncButtonPushed, true);
            app.GetInverseKinematicsikuncButton.Text = {'Get Inverse Kinematics'; '(ikunc)'};
            app.GetInverseKinematicsikconTakesjointlimitsifdefinedButton = uibutton(app.IIRobotCreationandKinematicsTab, 'push');
            app.GetInverseKinematicsikconTakesjointlimitsifdefinedButton.ButtonPushedFcn = createCallbackFcn(app, @GetInverseKinematicsikconTakesjointlimitsifdefinedButtonPushed, true);
            app.GetInverseKinematicsikconTakesjointlimitsifdefinedButton.Text = {'Get Inverse Kinematics'; '(ikcon)'; '(Takes joint limits if defined)'};
            app.IIIDifferentialKinematicsandDynamicsTab = uitab(app.TabGroup);
            app.IIIDifferentialKinematicsandDynamicsTab.Title = 'III. Differential Kinematics and Dynamics';
            app.GetJacobianEquationsButton = uibutton(app.IIIDifferentialKinematicsandDynamicsTab, 'push');
            app.GetJacobianEquationsButton.ButtonPushedFcn = createCallbackFcn(app, @GetJacobianEquationsButtonPushed, true);
            app.GetJacobianEquationsButton.Text = 'Get Jacobian Equations';
            app.GetNumericJacobianButton = uibutton(app.IIIDifferentialKinematicsandDynamicsTab, 'push');
            app.GetNumericJacobianButton.ButtonPushedFcn = createCallbackFcn(app, @GetNumericJacobianButtonPushed, true);
            app.GetNumericJacobianButton.Text = 'Get Numeric Jacobian';
            app.GetVelocityofEndEffectorXe_dotButton = uibutton(app.IIIDifferentialKinematicsandDynamicsTab, 'push');
            app.GetVelocityofEndEffectorXe_dotButton.ButtonPushedFcn = createCallbackFcn(app, @GetVelocityofEndEffectorXe_dotButtonPushed, true);
            app.GetVelocityofEndEffectorXe_dotButton.Text = {'Get Velocity of '; 'End Effector (Xe_dot)'};
            app.GetLagrangianEquationsButton = uibutton(app.IIIDifferentialKinematicsandDynamicsTab, 'push');
            app.GetLagrangianEquationsButton.ButtonPushedFcn = createCallbackFcn(app, @GetLagrangianEquationsButtonPushed, true);
            app.GetLagrangianEquationsButton.Text = 'Get Lagrangian Equations';
            app.UITable2 = uitable(app.IIIDifferentialKinematicsandDynamicsTab);
            app.UITable2.ColumnName = {'Sl. No.'; 'gear ratio (G)'; 'link mass (m)'; 'center of gravity (r)'; 'link inertia (I)'; 'motor inertia (Jm)'};
            app.UITable2.RowName = {};
            app.UITable2.ColumnEditable = [false true true true true true];
            app.UITable2.CellEditCallback = createCallbackFcn(app, @UITable2CellEdit, true);
            app.PlotendeffectorposeandvelocityButton_2 = uibutton(app.IIIDifferentialKinematicsandDynamicsTab, 'push');
            app.PlotendeffectorposeandvelocityButton_2.ButtonPushedFcn = createCallbackFcn(app, @PlotendeffectorposeandvelocityButton_2Pushed, true);
            app.PlotendeffectorposeandvelocityButton_2.Text = 'Plot end effector pose and velocity';
            app.PartFDifferentialKinematicsLabel = uilabel(app.IIIDifferentialKinematicsandDynamicsTab);
            app.PartFDifferentialKinematicsLabel.Text = 'Part F: Differential Kinematics';
            app.PartGInverseDifferentialKinematicsLabel = uilabel(app.IIIDifferentialKinematicsandDynamicsTab);
            app.PartGInverseDifferentialKinematicsLabel.Text = 'Part G: Inverse Differential Kinematics';
            app.PartHDynamicsLabel = uilabel(app.IIIDifferentialKinematicsandDynamicsTab);
            app.PartHDynamicsLabel.Text = 'Part H: Dynamics';
            app.GetJointVelocitiesq_dotButton = uibutton(app.IIIDifferentialKinematicsandDynamicsTab, 'push');
            app.GetJointVelocitiesq_dotButton.ButtonPushedFcn = createCallbackFcn(app, @GetJointVelocitiesq_dotButtonPushed, true);
            app.GetJointVelocitiesq_dotButton.Text = 'Get Joint Velocities (q_dot)';
            app.ForconstantmotortorqueTcEditFieldLabel = uilabel(app.IIIDifferentialKinematicsandDynamicsTab);
            app.ForconstantmotortorqueTcEditFieldLabel.Text = {'For constant '; 'motor torque (Tc)'};
            app.ForconstantmotortorqueTcEditField = uieditfield(app.IIIDifferentialKinematicsandDynamicsTab, 'text');
            app.ForconstantmotortorqueTcEditField.ValueChangedFcn = createCallbackFcn(app, @ForconstantmotortorqueTcEditFieldValueChanged, true);
            app.Robotlinkposeq1xNradLabel = uilabel(app.IIIDifferentialKinematicsandDynamicsTab);
            app.Robotlinkposeq1xNradLabel.Text = {'Robot link pose ''q'' '; '(1xN) (rad): '};
            app.Robotlinkposeq1xNradEditField = uieditfield(app.IIIDifferentialKinematicsandDynamicsTab, 'text');
            app.Robotlinkposeq1xNradEditField.ValueChangedFcn = createCallbackFcn(app, @Robotlinkposeq1xNradEditFieldValueChanged, true);
            app.Robotlinkvelocityq_dot1xNradEditFieldLabel = uilabel(app.IIIDifferentialKinematicsandDynamicsTab);
            app.Robotlinkvelocityq_dot1xNradEditFieldLabel.Text = {'Robot link velocity ''q_dot'' '; '(1xN) (rad): '};
            app.Robotlinkvelocityq_dot1xNradEditField = uieditfield(app.IIIDifferentialKinematicsandDynamicsTab, 'text');
            app.Robotlinkvelocityq_dot1xNradEditField.ValueChangedFcn = createCallbackFcn(app, @Robotlinkvelocityq_dot1xNradEditFieldValueChanged, true);
            app.Gravityvectorg01xNdegLabel = uilabel(app.IIIDifferentialKinematicsandDynamicsTab);
            app.Gravityvectorg01xNdegLabel.Text = {'Gravity vector g0 '; '(1xN) (deg)'};
            app.Gravityvectorg01xNdegEditField = uieditfield(app.IIIDifferentialKinematicsandDynamicsTab, 'text');
            app.Gravityvectorg01xNdegEditField.ValueChangedFcn = createCallbackFcn(app, @gravityvectorEditFieldValueChanged, true);
            app.InitalEndEffectorPoseq01xNradEditFieldLabel = uilabel(app.IIIDifferentialKinematicsandDynamicsTab);
            app.InitalEndEffectorPoseq01xNradEditFieldLabel.Text = 'Inital End Effector Pose ''q0'' (1xN) (rad)';
            app.InitalEndEffectorPoseq01xNradEditField = uieditfield(app.IIIDifferentialKinematicsandDynamicsTab, 'text');
            app.InitalEndEffectorPoseq01xNradEditField.ValueChangedFcn = createCallbackFcn(app, @InitalEndEffectorPoseq01xNradEditFieldValueChanged, true);
            app.InitalEndEffectorVelocityqd01xNradsEditFieldLabel = uilabel(app.IIIDifferentialKinematicsandDynamicsTab);
            app.InitalEndEffectorVelocityqd01xNradsEditFieldLabel.Text = 'Inital End Effector Velocity ''qd0'' (1xN) (rad/s)';
            app.InitalEndEffectorVelocityqd01xNradsEditField = uieditfield(app.IIIDifferentialKinematicsandDynamicsTab, 'text');
            app.InitalEndEffectorVelocityqd01xNradsEditField.ValueChangedFcn = createCallbackFcn(app, @InitalEndEffectorVelocityqd01xNradsEditFieldValueChanged, true);
            app.Robotlinkposeq1xNradEditField_2Label = uilabel(app.IIIDifferentialKinematicsandDynamicsTab);
            app.Robotlinkposeq1xNradEditField_2Label.Text = {'Robot link pose ''q'' '; '(1xN) (rad): '};
            app.Robotlinkposeq1xNradEditField_2 = uieditfield(app.IIIDifferentialKinematicsandDynamicsTab, 'text');
            app.Robotlinkposeq1xNradEditField_2.ValueChangedFcn = createCallbackFcn(app, @Robotlinkposeq1xNradEditField_2ValueChanged, true);
            app.EndEffectorvelocityXe_dot1x6radrpyfixedframeEditFieldLabel = uilabel(app.IIIDifferentialKinematicsandDynamicsTab);
            app.EndEffectorvelocityXe_dot1x6radrpyfixedframeEditFieldLabel.Text = {'End Effector velocity ''Xe_dot'' '; '(1x6) (rad) (rpy fixed frame): '};
            app.EndEffectorvelocityXe_dot1x6radrpyfixedframeEditField = uieditfield(app.IIIDifferentialKinematicsandDynamicsTab, 'text');
            app.EndEffectorvelocityXe_dot1x6radrpyfixedframeEditField.ValueChangedFcn = createCallbackFcn(app, @EndEffectorvelocityXe_dot1x6radrpyfixedframeEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end